classdef gui_hdd < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RobustControlofaHardDiskDriverLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        ControllersTab                 matlab.ui.container.Tab
        controllerLabel                matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        HButton                        matlab.ui.control.Button
        HcontrollerLabel               matlab.ui.control.Label
        LQGButton                      matlab.ui.control.Button
        LQGcontrollerLabel             matlab.ui.control.Label
        PIDButton                      matlab.ui.control.Button
        PIDcontrollerLabel             matlab.ui.control.Label
        ComparingControllersTab        matlab.ui.container.Tab
        KFRButton                      matlab.ui.control.Button
        FrequencyresponseofallcontrollersLabel_3  matlab.ui.control.Label
        FrequencyresponseoftheclosedloopsystemsLabel  matlab.ui.control.Label
        CLFRButton                     matlab.ui.control.Button
        CLTRButton                     matlab.ui.control.Button
        TransientresponseoftheclosedloopsystemsLabel  matlab.ui.control.Label
        NPButton                       matlab.ui.control.Button
        NominalperformanceoftheclosedloopsystemsLabel  matlab.ui.control.Label
        RSButton                       matlab.ui.control.Button
        RobuststabilityoftheclosedloopsystemsLabel  matlab.ui.control.Label
        RPButton                       matlab.ui.control.Button
        RobustperformanceoftheclosedloopsystemsLabel  matlab.ui.control.Label
        WeightingFunctionsTab          matlab.ui.container.Tab
        WpButton                       matlab.ui.control.Button
        PerformanceWeightingFunctionLabel  matlab.ui.control.Label
        WuButton                       matlab.ui.control.Button
        ControlWeightingFunctionLabel  matlab.ui.control.Label
        MButton                        matlab.ui.control.Button
        IdealModelLabel                matlab.ui.control.Label
        WnButton                       matlab.ui.control.Button
        NoiseShapingFunctionLabel      matlab.ui.control.Label
    end


    methods (Access = private)

        % Callback function
        function InitButtonPushed(app, event)
            mod_hdd
            olp_hdd
            sim_hdd
        end

        % Button pushed function: Button
        function muButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            %evalin('base','olp_hdd')
           % evalin('base','sim_hdd')
            %evalin('base','dk_hdd')
            dk_hdd
            ms_hdd
            mu_hdd
            frs_hdd
            clp_hdd
        end

        % Callback function
        function WeightingfunctionsEditFieldValueChanged(app, event)
            value = app.WeightingfunctionsEditField.Value;
            assignin('base','num',value);
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            hinf_hdd
            mu_hdd
            frs_hdd
            clp_hdd
        end

        % Button pushed function: LQGButton
        function LQGButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            lqg_hdd
            mu_hdd
            frs_hdd
            clp_hdd
        end

        % Button pushed function: PIDButton
        function PIDButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            pid_hdd
            mu_hdd
            frs_hdd
            clp_hdd
        end

        % Button pushed function: KFRButton
        function KFRButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            kf_hdd
        end

        % Button pushed function: CLFRButton
        function CLFRButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            cfr_hdd
        end

        % Button pushed function: CLTRButton
        function CLTRButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            ctr_hdd
        end

        % Button pushed function: NPButton
        function NPButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            prf_hdd
        end

        % Button pushed function: RSButton
        function RSButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            rbs_hdd
        end

        % Button pushed function: RPButton
        function RPButtonPushed(app, event)
            evalin('base','mod_hdd')
            olp_hdd
            sim_hdd
            load('KMU.mat')
            load('KHIN.mat')
            load('KLQG.mat')
            load('KPID.mat')
            rbp_hdd
        end

        % Button pushed function: WpButton
        function WpButtonPushed(app, event)
            performance_weights
        end

        % Button pushed function: WuButton
        function WuButtonPushed(app, event)
            control_weights
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            model_hdd
        end

        % Button pushed function: WnButton
        function WnButtonPushed(app, event)
            noise_weight
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.RobustControlofaHardDiskDriverLabel = uilabel(app.UIFigure);
            app.RobustControlofaHardDiskDriverLabel.Text = 'Robust Control of a Hard Disk Driver';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControllersTab = uitab(app.TabGroup);
            app.ControllersTab.Title = 'Controllers';
            app.ControllersTab.Units = 'pixels';
            app.controllerLabel = uilabel(app.ControllersTab);
            app.controllerLabel.Text = 'μ controller';
            app.Button = uibutton(app.ControllersTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @muButtonPushed, true);
            app.Button.Text = 'μ';
            app.HButton = uibutton(app.ControllersTab, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H∞ ';
            app.HcontrollerLabel = uilabel(app.ControllersTab);
            app.HcontrollerLabel.Text = 'H∞ controller';
            app.LQGButton = uibutton(app.ControllersTab, 'push');
            app.LQGButton.ButtonPushedFcn = createCallbackFcn(app, @LQGButtonPushed, true);
            app.LQGButton.Text = 'LQG';
            app.LQGcontrollerLabel = uilabel(app.ControllersTab);
            app.LQGcontrollerLabel.Text = 'LQG controller';
            app.PIDButton = uibutton(app.ControllersTab, 'push');
            app.PIDButton.ButtonPushedFcn = createCallbackFcn(app, @PIDButtonPushed, true);
            app.PIDButton.Text = 'PID';
            app.PIDcontrollerLabel = uilabel(app.ControllersTab);
            app.PIDcontrollerLabel.Text = 'PID controller';
            app.ComparingControllersTab = uitab(app.TabGroup);
            app.ComparingControllersTab.Title = 'Comparing Controllers';
            app.ComparingControllersTab.Units = 'pixels';
            app.KFRButton = uibutton(app.ComparingControllersTab, 'push');
            app.KFRButton.ButtonPushedFcn = createCallbackFcn(app, @KFRButtonPushed, true);
            app.KFRButton.Text = 'KFR';
            app.FrequencyresponseofallcontrollersLabel_3 = uilabel(app.ComparingControllersTab);
            app.FrequencyresponseofallcontrollersLabel_3.Text = 'Frequency response of all controllers';
            app.FrequencyresponseoftheclosedloopsystemsLabel = uilabel(app.ComparingControllersTab);
            app.FrequencyresponseoftheclosedloopsystemsLabel.Text = 'Frequency response of the closed-loop systems';
            app.CLFRButton = uibutton(app.ComparingControllersTab, 'push');
            app.CLFRButton.ButtonPushedFcn = createCallbackFcn(app, @CLFRButtonPushed, true);
            app.CLFRButton.Text = 'CLFR';
            app.CLTRButton = uibutton(app.ComparingControllersTab, 'push');
            app.CLTRButton.ButtonPushedFcn = createCallbackFcn(app, @CLTRButtonPushed, true);
            app.CLTRButton.Text = 'CLTR';
            app.TransientresponseoftheclosedloopsystemsLabel = uilabel(app.ComparingControllersTab);
            app.TransientresponseoftheclosedloopsystemsLabel.Text = 'Transient response of the closed-loop systems';
            app.NPButton = uibutton(app.ComparingControllersTab, 'push');
            app.NPButton.ButtonPushedFcn = createCallbackFcn(app, @NPButtonPushed, true);
            app.NPButton.Text = 'NP';
            app.NominalperformanceoftheclosedloopsystemsLabel = uilabel(app.ComparingControllersTab);
            app.NominalperformanceoftheclosedloopsystemsLabel.Text = {'Nominal performance of the '; 'closed-loop systems'};
            app.RSButton = uibutton(app.ComparingControllersTab, 'push');
            app.RSButton.ButtonPushedFcn = createCallbackFcn(app, @RSButtonPushed, true);
            app.RSButton.Text = 'RS';
            app.RobuststabilityoftheclosedloopsystemsLabel = uilabel(app.ComparingControllersTab);
            app.RobuststabilityoftheclosedloopsystemsLabel.Text = {'Robust stability of the '; 'closed-loop systems'};
            app.RPButton = uibutton(app.ComparingControllersTab, 'push');
            app.RPButton.ButtonPushedFcn = createCallbackFcn(app, @RPButtonPushed, true);
            app.RPButton.Text = 'RP';
            app.RobustperformanceoftheclosedloopsystemsLabel = uilabel(app.ComparingControllersTab);
            app.RobustperformanceoftheclosedloopsystemsLabel.Text = {'Robust performance of the '; 'closed-loop systems'};
            app.WeightingFunctionsTab = uitab(app.TabGroup);
            app.WeightingFunctionsTab.Title = 'Weighting Functions';
            app.WeightingFunctionsTab.Units = 'pixels';
            app.WpButton = uibutton(app.WeightingFunctionsTab, 'push');
            app.WpButton.ButtonPushedFcn = createCallbackFcn(app, @WpButtonPushed, true);
            app.WpButton.Text = 'Wp';
            app.PerformanceWeightingFunctionLabel = uilabel(app.WeightingFunctionsTab);
            app.PerformanceWeightingFunctionLabel.Text = 'Performance Weighting Function';
            app.WuButton = uibutton(app.WeightingFunctionsTab, 'push');
            app.WuButton.ButtonPushedFcn = createCallbackFcn(app, @WuButtonPushed, true);
            app.WuButton.Text = 'Wu';
            app.ControlWeightingFunctionLabel = uilabel(app.WeightingFunctionsTab);
            app.ControlWeightingFunctionLabel.Text = 'Control Weighting Function';
            app.MButton = uibutton(app.WeightingFunctionsTab, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.IdealModelLabel = uilabel(app.WeightingFunctionsTab);
            app.IdealModelLabel.Text = 'Ideal Model';
            app.WnButton = uibutton(app.WeightingFunctionsTab, 'push');
            app.WnButton.ButtonPushedFcn = createCallbackFcn(app, @WnButtonPushed, true);
            app.WnButton.Text = 'Wn';
            app.NoiseShapingFunctionLabel = uilabel(app.WeightingFunctionsTab);
            app.NoiseShapingFunctionLabel.Text = 'Noise Shaping Function';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_hdd()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end